classdef coastlinePredictionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CoastlinePredictionVisualizationAppLabel  matlab.ui.control.Label
        StartpredictionvisualizationButton  matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        LocationDropDown       matlab.ui.control.DropDown
        LocationDropDownLabel  matlab.ui.control.Label
        ScenarioDropDown       matlab.ui.control.DropDown
        ScenarioDropDownLabel  matlab.ui.control.Label
        AddoffsetCheckBox      matlab.ui.control.CheckBox
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %location  % array of locations
        %scenarios % array of scenarios
        data;
    end
    
    methods (Access = private)
        
        function getData(app)
            T = readtable('data/SLR_TF U.S. Sea Level Projections.csv');
            %remove metadata
            app.data = T(19:end,:);
            %take location colum with unique values only
            locations = table2array(unique(app.data(:,1)));
            scenarios = table2array(unique(app.data(:,10)));
            app.LocationDropDown.Items = locations;
            app.ScenarioDropDown.Items = scenarios;

        end
        
        function getRSLValue(app)
            %find the prediction row relative to the selected location and
            %scenario
            idx1 = find(strcmp(app.data.MeanSeaLevelDatasetFor_GlobalAndRegionalSeaLevelRiseScenariosFo,app.LocationDropDown.Value));
            idx2 = find(strcmp(app.data.Var10,app.ScenarioDropDown.Value));
            idx = intersect(idx1,idx2);
            predictionRow = table2array(app.data(idx,14:end));
            predictionYears = 2020:10:2150;
            yearRow = [2005, predictionYears];
            %plot RSL prediction values just as an example            
            plot(app.UIAxes, yearRow, predictionRow);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            getData(app);
        end

        % Button pushed function: StartpredictionvisualizationButton
        function StartpredictionvisualizationButtonPushed(app, event)
            getRSLValue(app);

            %visualization code can go here    

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.AddoffsetCheckBox = uicheckbox(app.Panel);
            app.AddoffsetCheckBox.Text = 'Add offset ';
            app.ScenarioDropDownLabel = uilabel(app.Panel);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropDown = uidropdown(app.Panel);
            app.ScenarioDropDown.Items = {};
            app.ScenarioDropDown.Value = {};
            app.LocationDropDownLabel = uilabel(app.Panel);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.Panel);
            app.LocationDropDown.Items = {};
            app.LocationDropDown.Value = {};
            app.StartpredictionvisualizationButton = uibutton(app.UIFigure, 'push');
            app.StartpredictionvisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @StartpredictionvisualizationButtonPushed, true);
            app.StartpredictionvisualizationButton.Text = 'Start prediction visualization';
            app.CoastlinePredictionVisualizationAppLabel = uilabel(app.UIFigure);
            app.CoastlinePredictionVisualizationAppLabel.Text = 'Coastline Prediction Visualization App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coastlinePredictionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end